classdef app_FindMatchingComp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UndoButton                     matlab.ui.control.Button
        FinishButton                   matlab.ui.control.Button
        PressPairupifamatchingcompartmentisfoundLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PairupButton                   matlab.ui.control.Button
        SelectthematchingcompartmentfromthelistLabel  matlab.ui.control.Label
        ClickonNametoentertheunpairedcompartmentfromthetableLabel  matlab.ui.control.Label
        ListofunpairedcompartmentsfromtheLipidModuleLabel  matlab.ui.control.Label
        CompartmentNameEditField       matlab.ui.control.EditField
        CompartmentNameEditFieldLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
    end


    properties (Access = private)
        temCompList % List of compartments from Template model
        unpairedCompTable % Table with Lipid Module compartments that were nor paired
        pairedCompartments % List with compartments paired by the user
        unpairedCompartments % List with compartments that were not paired by the user
        row_idx1 % Index of row for selected compartment 'Name' in Lipid Module list
        col_idx % Index of column for selected compartment 'Name' in Lipid Module list
        selected_DropDown % Compartment selected from the Template model
        new_row % Row contents for the "Paired up" compartments from the Lipid Module list
        oldUITable % Contain all compartments from Lipid Module before a matched element is eliminated from the list
        oldDropDown % Contain all compartments from Module Template before a matched element is eliminated from the list
        
        
    end
    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Upload files to execute startup function
            
            % Load list of Model Template compartments:
            pathTmCompList = fullfile('OutputFiles','TemplateCompList.mat');
            app.temCompList = load(pathTmCompList); %Load compartments list from Template model
            app.DropDown.Items = app.temCompList.templateCompList;
            app.DropDown.Items = [app.DropDown.Items,{'None'}];
            delete(pathTmCompList)
            
            % Load list of non-paired compartments from Lipid Module:
            pathUnpComps = fullfile('OutputFiles','UnpairedCompTable.mat');
            app.unpairedCompTable = load(pathUnpComps);
            app.UITable.Data = app.unpairedCompTable.unpairedCompTable;
            app.unpairedCompTable.Properties.VariableNames{1} = 'Abbreviation';
            app.unpairedCompTable.Properties.VariableNames{2} = 'Name';
            app.UITable.ColumnName = app.unpairedCompTable.Properties.VariableNames;
            app.CompartmentNameEditField.Value = 'Select compartment';
            delete(pathUnpComps)
            
            % 2. Create empty tables to fill data from paired and unpaired compartments
            app.pairedCompartments = cell2table(cell(1,4),'VariableNames',{'LM_Abb','LM_Name','Tm_Abb','Tm_Name'}); %Create empty table to concatenate 'Paired up' compartments 
            app.unpairedCompartments = cell2table(cell(1,2),'VariableNames',{'LM_Abb','LM_Name'});
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Get compartment 'Name' from Lipid Module that is paired up
            app.row_idx1 = event.Indices(1);
            app.col_idx = event.Indices(2);
            app.CompartmentNameEditField.Value = string(app.UITable.Data{app.row_idx1,app.col_idx});
            
        end

        % Button pushed function: PairupButton
        function PairupButtonPushed(app, event)
            % 3. Get data from UITable and Drop-down list
            UIdata = app.UITable.Data; 
            DropDownList = app.DropDown.Items;
            
            % 4. Find the row of the compartment 'Name' (col. 2) selected from the Lipid Module list:
            row_idx2 = find(strcmp(app.CompartmentNameEditField.Value, table2cell(UIdata(:,2))));
            
             % Get name of compartment selected from the Drop-down list
            app.selected_DropDown = app.DropDown.Value;
            
            % Get number of characters from item selected in Drop-down list, to prevent selecting abbreviation insted of name
            str_idx = strlength(app.CompartmentNameEditField.Value);
            idx3 = find(~cellfun(@isempty,(strfind(DropDownList, app.selected_DropDown))));
            
            % Don´t execute pairing if the abbreviation of the compartment is selected instead of the name:
            if str_idx <= 3
            
            %Execute pairing when name of compartment is selected:
            elseif size(UIdata,1) > 1
                
                % The compartment of Lipid Module has a matching compartment in the Template Model, different to option 'None':
                if strcmp(app.selected_DropDown, 'None') == 0 && strcmp(app.CompartmentNameEditField.Value,'Select compartment') == 0
                    
                    %Extract row contents from the Lipid Module list for the compartments "Paired up"
                    app.new_row = cell2table({UIdata{row_idx2,1},UIdata{row_idx2,2}});
                    %app.CompToDeleteLM = app.New_row; % Create variable with matched compartment from Lipid Module
                    app.new_row.Properties.VariableNames = {'LM_Abb','LM_Name'};
                    
                    %Extract matching compartment from Template model list
                    new_row_Col3 = cell2table(split((DropDownList(idx3)),",")');
                    
                    %Variable names harmonization
                    new_row_Col3.Properties.VariableNames = {'Tm_Abb','Tm_Name'};
                    new_row2 = [app.new_row, new_row_Col3];
                    
                    %Add "Paired up" compartments to PairedCompartments table
                    app.pairedCompartments = [app.pairedCompartments; new_row2];
                    app.CompartmentNameEditField.Value = 'Select compartment';
                    
                    % Delete "Paired up" compartment from Lipid Module list
                    app.oldUITable = app.UITable.Data;
                    app.UITable.Data(row_idx2,:) = [];
                    
                    % Keep 'None' option in the Template Model Drop-down compartments list:
                    if strcmp(app.selected_DropDown, 'None') == 1
                    
                    % If a compartment name differet to 'None' is selected from Template Model Drop-down compartments list, it has to be deleted:
                    else
                        app.oldDropDown = app.DropDown.Items;
                        DropDownList(idx3) = [];
                        app.DropDown.Items= DropDownList;
                    end
                
                % The compartment of Lipid Module has not matching compartment in the Template Model:    
                else
                    %Extracts row contents for the compartments from the Lipid Module list that had not matching compartment
                    app.new_row = cell2table({UIdata{row_idx2,1},UIdata{row_idx2,2}});
                    app.new_row.Properties.VariableNames = {'LM_Abb','LM_Name'}; 
                    
                    %Add "not-Paired up" compartments to UnpairedCompartments table
                    app.unpairedCompartments = [app.unpairedCompartments;app.new_row];
                    app.CompartmentNameEditField.Value = 'Select compartment';
                    
                    %Delete "not-Paired up" compartment from Lipid Module list
                    app.oldUITable = app.UITable.Data;
                    app.UITable.Data(row_idx2,:) = [];
                end
            
            % Execute else statement when Lipid Module list has just one item left
            else
                UIdata = [UIdata;{'NA','NA'}];
                app.UITable.Data = UIdata;
                idx4 = find(strcmp(app.CompartmentNameEditField.Value, table2cell(UIdata(:,2))));
                
                if strcmp(app.selected_DropDown, 'None') == 0 && strcmp(app.CompartmentNameEditField.Value,'Select compartment') == 0
                    
                    %Extract row contents from the Lipid Module list for the last compartment "Paired up"
                    app.new_row = cell2table({UIdata{idx4,1},UIdata{idx4,2}});
                    app.new_row.Properties.VariableNames = {'LM_Abb','LM_Name'};
                    idx3 = find(~cellfun(@isempty,(strfind(DropDownList, app.selected_DropDown))));
                    
                    %Extracts matching compartment from Template model list
                    new_row_Col4 = cell2table(split((DropDownList(idx3)),",")');
                    new_row_Col4.Properties.VariableNames = {'Tm_Abb','Tm_Name'};
                    new_row2 = [app.new_row, new_row_Col4];
                    app.pairedCompartments = [app.pairedCompartments; new_row2];
                    app.CompartmentNameEditField.Value = 'NA';
                    
                    % Delete last "Paired up" compartment from Lipid Module list
                    app.oldUITable = app.UITable.Data;
                    app.UITable.Data(idx4,:) = [];
                else 
                    
                    %Extracts row contents for the compartments from the last item from Lipid Module list that had not matching compartment
                    app.new_row = cell2table({UIdata{idx4,1},UIdata{idx4,2}});
                    app.new_row.Properties.VariableNames = {'LM_Abb','LM_Name'};
                    app.unpairedCompartments = [app.unpairedCompartments;app.new_row];
                    app.CompartmentNameEditField.Value = 'NA';
                    
                    % Delete last "not-Paired up" compartment from Lipid Module list
                    app.oldUITable = app.UITable.Data;
                    app.UITable.Data(idx4,:) = [];
                end
            end
            
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
           
            % Undo selection if compartment 'Name' was mismatched with 'None' option from Template compartments list:
            if strcmp(app.selected_DropDown, 'None')
                % Restore compartment to UITable list:
                app.UITable.Data = app.oldUITable;
                
                % Erase compartment that was mismatched from 'PairedCompartment' List
                GetRowToDelete = size(app.pairedCompartments,1);
                app.unpairedCompartments(GetRowToDelete,:) = [];
            
            elseif ~strcmp(app.selected_DropDown, 'None')
                % Restore compartment to UITable list:
                app.UITable.Data = app.oldUITable;
                
                % Erase compartment that was mismatched from 'PairedCompartment' List
                GetRowToDelete = size(app.pairedCompartments,1);
                app.pairedCompartments(GetRowToDelete,:) = [];
                
                % Restore erased compartment to Template compartments list:
                app.DropDown.Items = app.oldDropDown;
            end
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            paired_Compartments = app.pairedCompartments;
            pathPairedComps = fullfile('OutputFiles','Paired_Compartments.mat');
            save(pathPairedComps, 'paired_Compartments')
            
            unpaired_Compartments = app.unpairedCompartments;
            pathUnpairedComps = fullfile('OutputFiles','Unpaired_Compartments.mat');
            save(pathUnpairedComps, 'unpaired_Compartments')
            
            closereq();
            CompHarmonization; %Execute CompHarmonization function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.CompartmentNameEditFieldLabel = uilabel(app.UIFigure);
            app.CompartmentNameEditFieldLabel.Text = 'Compartment Name';
            app.CompartmentNameEditField = uieditfield(app.UIFigure, 'text');
            app.ListofunpairedcompartmentsfromtheLipidModuleLabel = uilabel(app.UIFigure);
            app.ListofunpairedcompartmentsfromtheLipidModuleLabel.Text = 'List of unpaired compartments from the Lipid Module:';
            app.ClickonNametoentertheunpairedcompartmentfromthetableLabel = uilabel(app.UIFigure);
            app.ClickonNametoentertheunpairedcompartmentfromthetableLabel.Text = '1. Click on ''Name'' to enter the unpaired compartment from the table:';
            app.SelectthematchingcompartmentfromthelistLabel = uilabel(app.UIFigure);
            app.SelectthematchingcompartmentfromthelistLabel.Text = '2. Select the matching compartment from the list:';
            app.PairupButton = uibutton(app.UIFigure, 'push');
            app.PairupButton.ButtonPushedFcn = createCallbackFcn(app, @PairupButtonPushed, true);
            app.PairupButton.Text = 'Pair up';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PressPairupifamatchingcompartmentisfoundLabel = uilabel(app.UIFigure);
            app.PressPairupifamatchingcompartmentisfoundLabel.Text = '3. Press "Pair up" if a matching compartment is found:';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_FindMatchingComp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end